classdef radio_propagation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SignalpropagationUIFigure  matlab.ui.Figure
        Image                      matlab.ui.control.Image
        TxPowerEditFieldLabel      matlab.ui.control.Label
        TxPowNum                   matlab.ui.control.NumericEditField
        dBmLabel                   matlab.ui.control.Label
        dBmLabel_2                 matlab.ui.control.Label
        RxPowerEditFieldLabel      matlab.ui.control.Label
        RxPowNum                   matlab.ui.control.NumericEditField
        dBiLabel                   matlab.ui.control.Label
        GEditFieldLabel            matlab.ui.control.Label
        TxGNum                     matlab.ui.control.NumericEditField
        TxLabel                    matlab.ui.control.Label
        dBiLabel_2                 matlab.ui.control.Label
        RxLabel                    matlab.ui.control.Label
        GEditField_2Label          matlab.ui.control.Label
        RxGNum                     matlab.ui.control.NumericEditField
        kmLabel                    matlab.ui.control.Label
        DistanceEditFieldLabel     matlab.ui.control.Label
        DistNum                    matlab.ui.control.NumericEditField
        MHzLabel                   matlab.ui.control.Label
        FrequencyEditFieldLabel    matlab.ui.control.Label
        FreqNum                    matlab.ui.control.NumericEditField
        dBLabel                    matlab.ui.control.Label
        TxBNum                     matlab.ui.control.NumericEditField
        BEditFieldLabel            matlab.ui.control.Label
        TxLabel_2                  matlab.ui.control.Label
        dBLabel_2                  matlab.ui.control.Label
        RxBNum                     matlab.ui.control.NumericEditField
        RxLabel_2                  matlab.ui.control.Label
        GEditField_3Label_2        matlab.ui.control.Label
        PLNum                      matlab.ui.control.Label
        txPowMwLabel               matlab.ui.control.Label
        txEirpLabel                matlab.ui.control.Label
        rxPowMwLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        txPow = 14;
        txB = 0;
        txG = 0;
        dist = 1;
        freq = 868;
        rxG = 0;
        rxB = 0;
    end
    
    methods (Access = private)
        
        function getValues(app)
            app.txPow = app.TxPowNum.Value;
            app.txG = app.TxGNum.Value;
            app.txB = app.TxBNum.Value;
            app.dist = app.DistNum.Value;
            app.freq = app.FreqNum.Value;
            app.rxG = app.RxGNum.Value;
            app.rxB = app.RxBNum.Value;
        end
        
        function calculatePL(app)
            txEIRP = app.txPow - app.txB + app.txG;
            pl = 20 * log10(app.dist) + 20 * log10(app.freq) + 32.44;
            rxPow = txEIRP - pl + app.rxG - app.rxB;
            app.PLNum.Text = sprintf('L = %.2f dB', pl);
            app.RxPowNum.Value = rxPow;
            app.txPowMwLabel.Text = sprintf('(%.2f mW)', 10^(app.txPow/10));
            app.txEirpLabel.Text = sprintf('(EIRP %.2f dBm)', txEIRP);
            app.rxPowMwLabel.Text = sprintf('(%d mW)', 10^(rxPow/10));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.calculatePL();
        end

        % Value changed function: TxPowNum
        function TxPowNumValueChanged(app, event)
            app.getValues();
            app.calculatePL();
        end

        % Value changed function: TxBNum
        function TxBNumValueChanged(app, event)
            app.getValues();
            app.calculatePL();
        end

        % Value changed function: TxGNum
        function TxGNumValueChanged(app, event)
            app.getValues();
            app.calculatePL();
        end

        % Value changed function: DistNum
        function DistNumValueChanged(app, event)
            app.getValues();
            app.calculatePL();
        end

        % Value changed function: FreqNum
        function FreqNumValueChanged(app, event)
            app.getValues();
            app.calculatePL();
        end

        % Value changed function: RxGNum
        function RxGNumValueChanged(app, event)
            app.getValues();
            app.calculatePL();
        end

        % Value changed function: RxBNum
        function RxBNumValueChanged(app, event)
            app.getValues();
            app.calculatePL();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SignalpropagationUIFigure = uifigure('Visible', 'off');
            app.SignalpropagationUIFigure.Name = 'Signal propagation';
            app.Image = uiimage(app.SignalpropagationUIFigure);
            app.Image.ImageSource = 'radio_propagation.svg';
            app.TxPowerEditFieldLabel = uilabel(app.SignalpropagationUIFigure);
            app.TxPowerEditFieldLabel.Text = 'Tx Power';
            app.TxPowNum = uieditfield(app.SignalpropagationUIFigure, 'numeric');
            app.TxPowNum.ValueChangedFcn = createCallbackFcn(app, @TxPowNumValueChanged, true);
            app.TxPowNum.Tag = 'txPowNum';
            app.TxPowNum.Tooltip = {'Transmitter power'};
            app.TxPowNum.Value = 14;
            app.dBmLabel = uilabel(app.SignalpropagationUIFigure);
            app.dBmLabel.Text = 'dBm';
            app.dBmLabel_2 = uilabel(app.SignalpropagationUIFigure);
            app.dBmLabel_2.Text = 'dBm';
            app.RxPowerEditFieldLabel = uilabel(app.SignalpropagationUIFigure);
            app.RxPowerEditFieldLabel.Text = 'Rx Power';
            app.RxPowNum = uieditfield(app.SignalpropagationUIFigure, 'numeric');
            app.RxPowNum.Editable = 'off';
            app.RxPowNum.Tooltip = {'Received power'};
            app.RxPowNum.Value = -77.21;
            app.dBiLabel = uilabel(app.SignalpropagationUIFigure);
            app.dBiLabel.Text = 'dBi';
            app.GEditFieldLabel = uilabel(app.SignalpropagationUIFigure);
            app.GEditFieldLabel.Text = 'G';
            app.TxGNum = uieditfield(app.SignalpropagationUIFigure, 'numeric');
            app.TxGNum.ValueChangedFcn = createCallbackFcn(app, @TxGNumValueChanged, true);
            app.TxGNum.Tag = 'txGNum';
            app.TxGNum.Tooltip = {'Transmitter antenna gain'};
            app.TxLabel = uilabel(app.SignalpropagationUIFigure);
            app.TxLabel.Text = 'Tx';
            app.dBiLabel_2 = uilabel(app.SignalpropagationUIFigure);
            app.dBiLabel_2.Text = 'dBi';
            app.RxLabel = uilabel(app.SignalpropagationUIFigure);
            app.RxLabel.Text = 'Rx';
            app.GEditField_2Label = uilabel(app.SignalpropagationUIFigure);
            app.GEditField_2Label.Text = 'G';
            app.RxGNum = uieditfield(app.SignalpropagationUIFigure, 'numeric');
            app.RxGNum.ValueChangedFcn = createCallbackFcn(app, @RxGNumValueChanged, true);
            app.RxGNum.Tag = 'rxGNum';
            app.RxGNum.Tooltip = {'Receiver antenna gain'};
            app.kmLabel = uilabel(app.SignalpropagationUIFigure);
            app.kmLabel.Text = 'km';
            app.DistanceEditFieldLabel = uilabel(app.SignalpropagationUIFigure);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistNum = uieditfield(app.SignalpropagationUIFigure, 'numeric');
            app.DistNum.Limits = [0 Inf];
            app.DistNum.ValueChangedFcn = createCallbackFcn(app, @DistNumValueChanged, true);
            app.DistNum.Tag = 'distNum';
            app.DistNum.Tooltip = {'Distance between transmitter and receiver'};
            app.DistNum.Value = 1;
            app.MHzLabel = uilabel(app.SignalpropagationUIFigure);
            app.MHzLabel.Text = 'MHz';
            app.FrequencyEditFieldLabel = uilabel(app.SignalpropagationUIFigure);
            app.FrequencyEditFieldLabel.Tooltip = {''};
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FreqNum = uieditfield(app.SignalpropagationUIFigure, 'numeric');
            app.FreqNum.Limits = [0 Inf];
            app.FreqNum.ValueChangedFcn = createCallbackFcn(app, @FreqNumValueChanged, true);
            app.FreqNum.Tag = 'freqNum';
            app.FreqNum.Tooltip = {'Carrier frequency'};
            app.FreqNum.Value = 868;
            app.dBLabel = uilabel(app.SignalpropagationUIFigure);
            app.dBLabel.Text = 'dB';
            app.TxBNum = uieditfield(app.SignalpropagationUIFigure, 'numeric');
            app.TxBNum.ValueChangedFcn = createCallbackFcn(app, @TxBNumValueChanged, true);
            app.TxBNum.Tag = 'txBNum';
            app.TxBNum.Tooltip = {'Transmitter insertion loss'};
            app.BEditFieldLabel = uilabel(app.SignalpropagationUIFigure);
            app.BEditFieldLabel.Text = 'B';
            app.TxLabel_2 = uilabel(app.SignalpropagationUIFigure);
            app.TxLabel_2.Text = 'Tx';
            app.dBLabel_2 = uilabel(app.SignalpropagationUIFigure);
            app.dBLabel_2.Text = 'dB';
            app.RxBNum = uieditfield(app.SignalpropagationUIFigure, 'numeric');
            app.RxBNum.ValueChangedFcn = createCallbackFcn(app, @RxBNumValueChanged, true);
            app.RxBNum.Tag = 'rxBNum';
            app.RxBNum.Tooltip = {'Receiver insertion loss'};
            app.RxLabel_2 = uilabel(app.SignalpropagationUIFigure);
            app.RxLabel_2.Text = 'Rx';
            app.GEditField_3Label_2 = uilabel(app.SignalpropagationUIFigure);
            app.GEditField_3Label_2.Text = 'B';
            app.PLNum = uilabel(app.SignalpropagationUIFigure);
            app.PLNum.Tooltip = {'Free-Space model attenuation'};
            app.PLNum.Text = 'L = 0 dB';
            app.txPowMwLabel = uilabel(app.SignalpropagationUIFigure);
            app.txPowMwLabel.Text = '(25.12 mW)';
            app.txEirpLabel = uilabel(app.SignalpropagationUIFigure);
            app.txEirpLabel.Tooltip = {'Transmitter Equivalent Isotropically Radiated Power'};
            app.txEirpLabel.Text = '(EIRP 14.00 dBm)';
            app.rxPowMwLabel = uilabel(app.SignalpropagationUIFigure);
            app.rxPowMwLabel.Text = '(25.12 mW)';
            % Show the figure after all components are created
            app.SignalpropagationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = radio_propagation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SignalpropagationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SignalpropagationUIFigure)
        end
    end
end